classdef ActiveContoursApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        MethodButtonGroup              matlab.ui.container.ButtonGroup
        RegionButton                   matlab.ui.control.RadioButton
        EdgeButton                     matlab.ui.control.RadioButton
        CropButton                     matlab.ui.control.Button
        ROIButton                      matlab.ui.control.Button
        ImageFromlistListBoxLabel      matlab.ui.control.Label
        ImageFromlist                  matlab.ui.control.ListBox
        ChoosefromfileButton           matlab.ui.control.Button
        ImagePreviewLabel              matlab.ui.control.Label
        ImageWindowButton              matlab.ui.control.StateButton
        ProgressSliderLabel            matlab.ui.control.Label
        ProgressSlider                 matlab.ui.control.Slider
        EvolveButton                   matlab.ui.control.Button
        MaxIterationsEditFieldLabel    matlab.ui.control.Label
        MaxIterationsEditField         matlab.ui.control.NumericEditField
        ContractionBiasEditFieldLabel  matlab.ui.control.Label
        ContractionBiasEditField       matlab.ui.control.NumericEditField
        SmoothFactorEditFieldLabel     matlab.ui.control.Label
        SmoothFactorEditField          matlab.ui.control.NumericEditField
        HistogramEqualizationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Paths ; % Class of all possible paths and images
        SegmentAlgo ; %Class to manage the parameters and execution of the Segmentation Algorithm 
        AnimationControl = struct('NumIterationsPerUpdate' , 2)
        ImagesManager ;  % Class to controls the Behaviour, Manipulations, Additions and Acquisition  of Images
        WindowsManager ; % Manges and controls all open apps and windows
        ApearanceDefaultVals = struct('DefaultButtonColor' , [0.96, 0.96,0.96] , ...
                                      'ActiveButtonColor'  , [0.5 , 0.5 ,0   ] , ...
                                      'WaitingButtonColor' , [0.2 , 0.6 , 1  ] ,...
                                      'MaskColor' , [0,1,0]);
    end
    
    properties (Access = public)
        is_busy_evolving = false;
    end
    
    methods (Access = private)
        
        function [] = read_user_chosen_image(app)
            chosen_image_str = app.ImageFromlist.Value;
            chosen_image_path = app.Paths.AvailablePictures.(chosen_image_str);
            Im = imread(chosen_image_path);
            app.ImagesManager.set_original_image(Im);           
        end % read_user_chosen_image


        function results = assert_correct_app_folder(app , currentDir)
            is_exist_folder         = [ false        ,  false           ,  false                ];
            folder_that_should_exist= [ "class_defs" , "app_functions"  ,"algorithm_functions"  ];
            
            listing = dir(currentDir);
            for i = 1:length(listing)
                file = listing(i);
                index_of_found_folder = find(folder_that_should_exist==file.name);
                is_exist_folder(index_of_found_folder) = true;
            end
            
            if ~all(is_exist_folder)
                ErrorMsg= sprintf("%s\n    - %s\n    - %s\n    - %s" , ...
                        "App Loading Error: App should run on its main folder with subfolders:  ", ...
                        folder_that_should_exist(1), ...
                        folder_that_should_exist(2), ...
                        folder_that_should_exist(3)    );
                closeFunc = @(h,e)  delete(app);
                uialert(app.MainWindow,ErrorMsg,"Wrong Loading Path" , 'CloseFcn', closeFunc );
                error(ErrorMsg);         
            
            end
        end
    end %private
    
    
    methods (Access = public) 
        
        function [] = show_image(app , Im )
            %Update figures:
            imshow( Im , 'Parent' , app.UIAxes);
            app.UIAxes.XLabel.Visible = "off";
            app.UIAxes.YLabel.Visible = "off";
            app.UIAxes.Title.Visible  = "off";
        end
        
    end %public
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App folder:
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                disp(status);
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end
            app.assert_correct_app_folder(currentDir) 
            
            %Continue creating the app's properties:
            app.Paths = PathsClass(string(currentDir) ,"search");
            app.WindowsManager = WindowsManagerClass(app) ; % Manges and controls all open apps and windows
            app.ImagesManager  = ImagesManagerClass(app.WindowsManager) ;
            app.SegmentAlgo    = SegmentationAlgoClass(app.ImagesManager); 
            
            %Available Algorithms and Images:
            app.AlgorithmDropDown.Items = AvailableAlgorithms.string_cell_array();
            app.ImageFromlist.Items = app.Paths.AvailablePictures_string_cell_array();
            
            %Take care of images:            
            app.read_user_chosen_image();
        end

        % Value changed function: ImageWindowButton
        function ImageWindowButtonValueChanged(app, event)
            app.WindowsManager.show_image_window();
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            app.WindowsManager.close("MainApp");
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            persistent is_waiting_for_second_press  roi
            if isempty(is_waiting_for_second_press)
                is_waiting_for_second_press = false;
                roi = [];
            end
            
            if is_waiting_for_second_press %Do the actuall Cropping:
                %Button visual as ready:
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
                %Crop:
                app.ImagesManager.crop(roi)
                %Reset for next crop attempts:
                is_waiting_for_second_press = false;
                delete(roi);
            else
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
                roi = drawrectangle(app.UIAxes);               
                app.CropButton.BackgroundColor = app.ApearanceDefaultVals.WaitingButtonColor;
                is_waiting_for_second_press = true;
            end
            
        end

        % Value changed function: ImageFromlist
        function ImageFromlistValueChanged(app, event)
            app.read_user_chosen_image();
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            app.ROIButton.BackgroundColor = app.ApearanceDefaultVals.ActiveButtonColor;
            roi = drawrectangle(app.UIAxes); 
            Mask = roi.createMask();
            %save results:
            app.SegmentAlgo.Params.Mask = Mask;
            delete(roi);
            app.ROIButton.BackgroundColor = app.ApearanceDefaultVals.DefaultButtonColor;
            % Update image to show   with the new roi:
            app.ImagesManager.add_mask(Mask, app.ApearanceDefaultVals);
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            app.SegmentAlgo.set("MatlabFunctionMethod" , app.AlgorithmDropDown.Value )
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            app.SegmentAlgo.set("AlgorithmFunction" , app.AlgorithmDropDown.Value)
        end

        % Value changed function: SmoothFactorEditField
        function SmoothFactorEditFieldValueChanged(app, event)
            app.SegmentAlgo.set("SmoothFactor" , app.SmoothFactorEditField.Value)
        end

        % Value changed function: ContractionBiasEditField
        function ContractionBiasEditFieldValueChanged(app, event)
            app.SegmentAlgo.set("ContractionBias" , app.ContractionBiasEditField.Value)
        end

        % Button pushed function: EvolveButton
        function EvolveButtonPushed(app, event)
            if app.is_busy_evolving 
                app.is_busy_evolving = false;
                app.SegmentAlgo.stop_algorithm()
            else
                app.is_busy_evolving = true;
                app.SegmentAlgo.stop_algorithm()       
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'Active Contours App ';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.Menu = uimenu(app.MainWindow);
            app.Menu.Text = '....';
            app.AlgorithmDropDownLabel = uilabel(app.MainWindow);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.MainWindow);
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.UIAxes = uiaxes(app.MainWindow);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MethodButtonGroup = uibuttongroup(app.MainWindow);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.RegionButton = uiradiobutton(app.MethodButtonGroup);
            app.RegionButton.Text = 'Region';
            app.RegionButton.Value = true;
            app.EdgeButton = uiradiobutton(app.MethodButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.CropButton = uibutton(app.MainWindow, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ROIButton = uibutton(app.MainWindow, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.ImageFromlistListBoxLabel = uilabel(app.MainWindow);
            app.ImageFromlistListBoxLabel.Text = 'Image (From list)';
            app.ImageFromlist = uilistbox(app.MainWindow);
            app.ImageFromlist.ValueChangedFcn = createCallbackFcn(app, @ImageFromlistValueChanged, true);
            app.ChoosefromfileButton = uibutton(app.MainWindow, 'push');
            app.ChoosefromfileButton.Text = 'Choose from file';
            app.ImagePreviewLabel = uilabel(app.MainWindow);
            app.ImagePreviewLabel.Text = 'Image Preview';
            app.ImageWindowButton = uibutton(app.MainWindow, 'state');
            app.ImageWindowButton.ValueChangedFcn = createCallbackFcn(app, @ImageWindowButtonValueChanged, true);
            app.ImageWindowButton.Text = 'Image Window';
            app.ProgressSliderLabel = uilabel(app.MainWindow);
            app.ProgressSliderLabel.Enable = 'off';
            app.ProgressSliderLabel.Text = 'Progress';
            app.ProgressSlider = uislider(app.MainWindow);
            app.ProgressSlider.Enable = 'off';
            app.EvolveButton = uibutton(app.MainWindow, 'push');
            app.EvolveButton.ButtonPushedFcn = createCallbackFcn(app, @EvolveButtonPushed, true);
            app.EvolveButton.Text = 'Evolve';
            app.MaxIterationsEditFieldLabel = uilabel(app.MainWindow);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.MainWindow, 'numeric');
            app.MaxIterationsEditField.Value = 1000;
            app.ContractionBiasEditFieldLabel = uilabel(app.MainWindow);
            app.ContractionBiasEditFieldLabel.Text = 'Contraction Bias';
            app.ContractionBiasEditField = uieditfield(app.MainWindow, 'numeric');
            app.ContractionBiasEditField.ValueChangedFcn = createCallbackFcn(app, @ContractionBiasEditFieldValueChanged, true);
            app.ContractionBiasEditField.Value = 0.01;
            app.SmoothFactorEditFieldLabel = uilabel(app.MainWindow);
            app.SmoothFactorEditFieldLabel.Text = 'Smooth Factor';
            app.SmoothFactorEditField = uieditfield(app.MainWindow, 'numeric');
            app.SmoothFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothFactorEditFieldValueChanged, true);
            app.SmoothFactorEditField.Value = 4;
            app.HistogramEqualizationCheckBox = uicheckbox(app.MainWindow);
            app.HistogramEqualizationCheckBox.Text = {'{Histogram ,'; 'Equalization}'};
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActiveContoursApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end